classdef IM_TS_Region < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GeneratorRegionLabel           matlab.ui.control.Label
        MotorRegionLabel               matlab.ui.control.Label
        BrakingRegionLabel             matlab.ui.control.Label
        R_0Label                       matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        PoleSpinner                    matlab.ui.control.Spinner
        PoleSpinnerLabel               matlab.ui.control.Label
        Frequencyvalue                 matlab.ui.control.NumericEditField
        FrequencyKnob                  matlab.ui.control.Knob
        FrequencyKnobLabel             matlab.ui.control.Label
        R_2timesR_0Slider              matlab.ui.control.Slider
        R_2timesR_0SliderLabel         matlab.ui.control.Label
        X_2EditField                   matlab.ui.control.NumericEditField
        X_2EditFieldLabel              matlab.ui.control.Label
        R_2EditField                   matlab.ui.control.NumericEditField
        R_2EditFieldLabel              matlab.ui.control.Label
        X_THEditField                  matlab.ui.control.NumericEditField
        X_THEditFieldLabel             matlab.ui.control.Label
        R_THEditField                  matlab.ui.control.NumericEditField
        R_THEditFieldLabel             matlab.ui.control.Label
        V_THEditField                  matlab.ui.control.NumericEditField
        V_THEditFieldLabel             matlab.ui.control.Label
        DifferentRegioninTSCurveLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tempapp % Description
        V_TH;
        R_TH;
        X_TH;
        Frequency;
        Pole;
    end
    
    methods (Access = private)
        
        function plot_TS(app, Axes, R_2,X_2)
            cla(Axes);
            app.Frequency = app.FrequencyKnob.Value;
            app.Pole = app.PoleSpinner.Value;
            n_sync = 120*app.Frequency/app.Pole;
            s = [-1:0.01:1.5];
            n_m = (1-s)*n_sync;
            w_sync = 2*pi*n_sync/60;
            
            Tau = zeros(1, length(n_m));
            for i = 1: length(n_m)
                Tau(i) = (3*(app.V_TH)^2*R_2/(w_sync*s(i)*((app.R_TH +(R_2/s(i)))^2 + (app.X_TH + X_2)^2)));
                if n_m(i) == n_sync
                    Tau(i) = 0;
                end
            end
            plot(Axes, n_m, Tau);
            hold(Axes, 'on');
            
            s_max = (R_2)/ (sqrt((app.R_TH)^2 + (app.X_TH + X_2)^2));
            n_mmax = (1- s_max)*n_sync;
            Tau_max = (3*(app.V_TH)^2)/(2*w_sync*(app.R_TH +sqrt((app.R_TH)^2 + (app.X_TH + X_2)^2)));
            plot(Axes, n_m, Tau_max*ones(1, length(n_m)), '--');
            plot(Axes, n_mmax, Tau_max, 'o');
            plot(Axes, n_mmax*ones(1, length(n_m)),Tau, '--');
            hold(Axes, 'off');
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IM_TS_Region'; %changing the app name
            
            %importing values from IM_Torque_Speed app
            app.tempapp = IM_Torque_Speed;
            
            app.V_TH = app.tempapp.V_TH;
            app.R_TH = app.tempapp.R_TH;
            app.X_TH = app.tempapp.X_TH; 
            
            app.V_THEditField.Value = app.V_TH;
            app.R_THEditField.Value = app.R_TH;
            app.X_THEditField.Value = app.X_TH;
            delete(app.tempapp.UIFigure); %deleting the instance of IM_Torque_Speed app
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            app.Frequencyvalue.Value = event.Value;
            
        end

        % Value changed function: Frequencyvalue
        function FrequencyvalueValueChanged(app, event)
            app.FrequencyKnob.Value = app.Frequencyvalue.Value;
            
        end

        % Callback function: PlotButton, R_2timesR_0Slider
        function PlotButtonPushed(app, event)
            
            app.V_TH = app.V_THEditField.Value;
            app.R_TH = app.R_THEditField.Value;
            app.X_TH = app.X_THEditField.Value;
            R_2 = app.R_2EditField.Value*app.R_2timesR_0Slider.Value;
            X_2 = app.X_2EditField.Value;
            
            plot_TS(app,app.UIAxes, R_2, X_2);
            
            
            app.GeneratorRegionLabel.Visible = 'on';
            app.MotorRegionLabel.Visible = 'on';
            app.BrakingRegionLabel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'n_m')
            ylabel(app.UIAxes, 'Torque(\tau_{ind})')
            zlabel(app.UIAxes, 'Z')
            app.DifferentRegioninTSCurveLabel = uilabel(app.UIFigure);
            app.DifferentRegioninTSCurveLabel.Text = 'Different Region in  TS Curve';
            app.V_THEditFieldLabel = uilabel(app.UIFigure);
            app.V_THEditFieldLabel.Text = 'V_TH';
            app.V_THEditField = uieditfield(app.UIFigure, 'numeric');
            app.R_THEditFieldLabel = uilabel(app.UIFigure);
            app.R_THEditFieldLabel.Text = 'R_TH';
            app.R_THEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_THEditFieldLabel = uilabel(app.UIFigure);
            app.X_THEditFieldLabel.Text = 'X_TH';
            app.X_THEditField = uieditfield(app.UIFigure, 'numeric');
            app.R_2EditFieldLabel = uilabel(app.UIFigure);
            app.R_2EditFieldLabel.Text = 'R_2';
            app.R_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R_2EditField.Limits = [1e-07 Inf];
            app.R_2EditField.ValueDisplayFormat = '%.3f';
            app.R_2EditField.Value = 0.332;
            app.X_2EditFieldLabel = uilabel(app.UIFigure);
            app.X_2EditFieldLabel.Text = 'X_2';
            app.X_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X_2EditField.Limits = [1e-07 Inf];
            app.X_2EditField.Value = 0.464;
            app.R_2timesR_0SliderLabel = uilabel(app.UIFigure);
            app.R_2timesR_0SliderLabel.Interpreter = 'latex';
            app.R_2timesR_0SliderLabel.Text = {'R_2'; '($\times R_0$)'};
            app.R_2timesR_0Slider = uislider(app.UIFigure);
            app.R_2timesR_0Slider.Limits = [1 5];
            app.R_2timesR_0Slider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.R_2timesR_0Slider.Value = 1;
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.Limits = [50 200];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.FrequencyKnob.Value = 60;
            app.Frequencyvalue = uieditfield(app.UIFigure, 'numeric');
            app.Frequencyvalue.ValueChangedFcn = createCallbackFcn(app, @FrequencyvalueValueChanged, true);
            app.Frequencyvalue.Value = 60;
            app.PoleSpinnerLabel = uilabel(app.UIFigure);
            app.PoleSpinnerLabel.Text = 'Pole';
            app.PoleSpinner = uispinner(app.UIFigure);
            app.PoleSpinner.Limits = [2 12];
            app.PoleSpinner.Value = 4;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.R_0Label = uilabel(app.UIFigure);
            app.R_0Label.Text = '*R_0';
            app.BrakingRegionLabel = uilabel(app.UIFigure);
            app.BrakingRegionLabel.Visible = 'off';
            app.BrakingRegionLabel.Text = 'Braking Region';
            app.MotorRegionLabel = uilabel(app.UIFigure);
            app.MotorRegionLabel.Visible = 'off';
            app.MotorRegionLabel.Text = 'Motor Region';
            app.GeneratorRegionLabel = uilabel(app.UIFigure);
            app.GeneratorRegionLabel.Visible = 'off';
            app.GeneratorRegionLabel.Text = 'Generator Region';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IM_TS_Region

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end