classdef Xer_3Phase_D_Y < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField2_2                    matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        jLabel_4                        matlab.ui.control.Label
        jLabel_3                        matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        jLabel_2                        matlab.ui.control.Label
        EditField2                      matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        jLabel                          matlab.ui.control.Label
        BaseValueofZ_VlowSideEditField  matlab.ui.control.NumericEditField
        BaseValueofZ_VlowSideEditFieldLabel  matlab.ui.control.Label
        BaseValueofZ_VhighSideEditField  matlab.ui.control.NumericEditField
        BaseValueofZ_VhighSideEditFieldLabel  matlab.ui.control.Label
        VoltageRegulationEditField      matlab.ui.control.NumericEditField
        VoltageRegulationEditFieldLabel  matlab.ui.control.Label
        SecondaryCktPhaseCurrentAEditField  matlab.ui.control.NumericEditField
        SecondaryCktPhaseCurrentAEditFieldLabel  matlab.ui.control.Label
        PrimaryCktPhaseCurrentAEditField  matlab.ui.control.NumericEditField
        PrimaryCktPhaseCurrentAEditFieldLabel  matlab.ui.control.Label
        ZPu_VlowSideEditField           matlab.ui.control.NumericEditField
        ZPu_VlowSideEditFieldLabel      matlab.ui.control.Label
        ZPu_VhighSideEditField          matlab.ui.control.NumericEditField
        ZPu_VhighSideEditFieldLabel     matlab.ui.control.Label
        BaseValueofIphase_VlowSideEditField  matlab.ui.control.NumericEditField
        BaseValueofIphase_VlowSideEditFieldLabel  matlab.ui.control.Label
        BaseValueofIphase_VhighSideEditField  matlab.ui.control.NumericEditField
        BaseValueofIphase_VhighSideEditFieldLabel  matlab.ui.control.Label
        TurnsRatioEditField             matlab.ui.control.NumericEditField
        TurnsRatioEditFieldLabel        matlab.ui.control.Label
        ReactivePowerPerPhaseVAREditField  matlab.ui.control.NumericEditField
        ReactivePowerPerPhaseVAREditFieldLabel  matlab.ui.control.Label
        RealPowerPerPhaseWEditField     matlab.ui.control.NumericEditField
        RealPowerPerPhaseWEditFieldLabel  matlab.ui.control.Label
        CircuitAnalysisButton           matlab.ui.control.Button
        PowerFactorEditField            matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel       matlab.ui.control.Label
        EquivalentReactanceOhmEditField  matlab.ui.control.NumericEditField
        EquivalentReactanceOhmEditFieldLabel  matlab.ui.control.Label
        EquivalentResistanceOhmEditField  matlab.ui.control.NumericEditField
        EquivalentResistanceOhmEditFieldLabel  matlab.ui.control.Label
        SecondaryLineVoltageVEditField  matlab.ui.control.NumericEditField
        SecondaryLineVoltageVEditFieldLabel  matlab.ui.control.Label
        PrimaryLineVoltageVEditField    matlab.ui.control.NumericEditField
        PrimaryLineVoltageVEditFieldLabel  matlab.ui.control.Label
        ApparentPowerVAEditField        matlab.ui.control.NumericEditField
        ApparentPowerVAEditFieldLabel   matlab.ui.control.Label
        PhaseDeltaWyeTransformersLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Xer 3Phase D-Y';
        end

        % Button pushed function: CircuitAnalysisButton
        function CircuitAnalysisButtonPushed(app, event)
            %Delta_wye
            %Taking Inputs
            S=app.ApparentPowerVAEditField.Value;
            Vlp=app.PrimaryLineVoltageVEditField.Value;
            Vls=app.SecondaryLineVoltageVEditField.Value;
            PF=app.PowerFactorEditField.Value;
            PFchar=app.DropDown.Value;
            R=app.EquivalentResistanceOhmEditField.Value;
            X=app.EquivalentReactanceOhmEditField.Value;
            
            %Real power per phase
            P=S*abs(PF);
            P_PerPhase=P/3
            app.RealPowerPerPhaseWEditField.Value=P_PerPhase;
            
            %Reactive power per phase
            Q=sqrt(S^2-P^2);
            Q_PerPhase=Q/3
            app.ReactivePowerPerPhaseVAREditField.Value=Q_PerPhase;
            
            %Turns ratio
            Vphase_primary=Vlp;
            Vphase_secondary=Vls/sqrt(3);
            a=Vphase_primary/Vphase_secondary;
            app.TurnsRatioEditField.Value=a;
            
            %Base value in high voltage side
            Iphase_high_vol=S/(3*Vphase_primary);
            app.BaseValueofIphase_VhighSideEditField.Value=Iphase_high_vol;
            Zphase_high_vol=3*(Vphase_primary^2)/S;
            app.BaseValueofZ_VhighSideEditField.Value=Zphase_high_vol;
            
            %Base value in low voltage side
            Iphase_low_vol=S/(3*Vphase_secondary);
            app.BaseValueofIphase_VlowSideEditField.Value=Iphase_low_vol;
            Zphase_low_vol=3*(Vphase_secondary^2)/S;
            app.BaseValueofZ_VlowSideEditField.Value=Zphase_low_vol;
            
            %Impedence in pu in high voltage side
            Zh_real=R/Zphase_high_vol;
            Zh_imaginary=X/Zphase_high_vol;
            app.ZPu_VhighSideEditField.Value=Zh_real;
            app.EditField_2.Value=Zh_imaginary;
            
            %Impedence in pu in low voltage side
            Zl_real=R/Zphase_low_vol;
            Zl_imaginary=X/Zphase_low_vol;
            app.ZPu_VlowSideEditField.Value=Zl_real;
            app.EditField2_2.Value=Zl_imaginary;
            
            %Primary Phase current
            if PFchar=="Leading"
                theta=acosd(PF);
            elseif PFchar=="Lagging"
                theta=-acosd(PF);
            else
                theta=acosd(1);
            end
            
            Ip=S/(3*Vphase_primary);
            Ipx=Ip*cosd(theta);
            Ipy=Ip*sind(theta);
            Ipcomplex=Ipx+Ipy*j;
            app.PrimaryCktPhaseCurrentAEditField.Value=Ipx;
            app.EditField.Value=Ipy;
            
            %Secondary Phase current
            Is=S/(3*Vphase_secondary);
            Isx=Is*cosd(theta);
            Isy=Is*sind(theta);
            Iscomplex=Isx+Isy*j;
            app.SecondaryCktPhaseCurrentAEditField.Value=Isx;
            app.EditField2.Value=Isy;
            
            %Voltage Regulation
            V_phi_P=a*Vphase_secondary+(R+X*j)*Ipcomplex;
            VR_num=abs(V_phi_P)-a*Vphase_secondary;
            VR_denom=a*Vphase_secondary;
            VR=(VR_num/VR_denom)*100;
            app.VoltageRegulationEditField.Value=VR;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhaseDeltaWyeTransformersLabel = uilabel(app.UIFigure);
            app.PhaseDeltaWyeTransformersLabel.Text = '3 Phase Delta Wye Transformers';
            app.ApparentPowerVAEditFieldLabel = uilabel(app.UIFigure);
            app.ApparentPowerVAEditFieldLabel.Text = 'Apparent Power(VA)';
            app.ApparentPowerVAEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApparentPowerVAEditField.Value = 50000;
            app.PrimaryLineVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryLineVoltageVEditFieldLabel.Text = 'Primary Line Voltage(V)';
            app.PrimaryLineVoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryLineVoltageVEditField.Value = 13800;
            app.SecondaryLineVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryLineVoltageVEditFieldLabel.Text = 'Secondary Line Voltage(V)';
            app.SecondaryLineVoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryLineVoltageVEditField.Value = 208;
            app.EquivalentResistanceOhmEditFieldLabel = uilabel(app.UIFigure);
            app.EquivalentResistanceOhmEditFieldLabel.Text = 'Equivalent Resistance(Ohm)';
            app.EquivalentResistanceOhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EquivalentResistanceOhmEditField.Value = 115;
            app.EquivalentReactanceOhmEditFieldLabel = uilabel(app.UIFigure);
            app.EquivalentReactanceOhmEditFieldLabel.Text = 'Equivalent Reactance(Ohm)';
            app.EquivalentReactanceOhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EquivalentReactanceOhmEditField.Value = 800;
            app.PowerFactorEditFieldLabel = uilabel(app.UIFigure);
            app.PowerFactorEditFieldLabel.Text = 'Power Factor';
            app.PowerFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerFactorEditField.Value = 0.8;
            app.CircuitAnalysisButton = uibutton(app.UIFigure, 'push');
            app.CircuitAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @CircuitAnalysisButtonPushed, true);
            app.CircuitAnalysisButton.Text = 'Circuit Analysis';
            app.RealPowerPerPhaseWEditFieldLabel = uilabel(app.UIFigure);
            app.RealPowerPerPhaseWEditFieldLabel.Text = 'Real Power Per Phase(W)';
            app.RealPowerPerPhaseWEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReactivePowerPerPhaseVAREditFieldLabel = uilabel(app.UIFigure);
            app.ReactivePowerPerPhaseVAREditFieldLabel.Text = 'Reactive  Power Per Phase(VAR)';
            app.ReactivePowerPerPhaseVAREditField = uieditfield(app.UIFigure, 'numeric');
            app.TurnsRatioEditFieldLabel = uilabel(app.UIFigure);
            app.TurnsRatioEditFieldLabel.Text = 'Turns Ratio';
            app.TurnsRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseValueofIphase_VhighSideEditFieldLabel = uilabel(app.UIFigure);
            app.BaseValueofIphase_VhighSideEditFieldLabel.Text = 'Base Value of Iphase_VhighSide';
            app.BaseValueofIphase_VhighSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseValueofIphase_VlowSideEditFieldLabel = uilabel(app.UIFigure);
            app.BaseValueofIphase_VlowSideEditFieldLabel.Text = 'Base Value of Iphase_VlowSide';
            app.BaseValueofIphase_VlowSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZPu_VhighSideEditFieldLabel = uilabel(app.UIFigure);
            app.ZPu_VhighSideEditFieldLabel.Text = 'Z(Pu)_VhighSide';
            app.ZPu_VhighSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZPu_VlowSideEditFieldLabel = uilabel(app.UIFigure);
            app.ZPu_VlowSideEditFieldLabel.Text = 'Z(Pu)_VlowSide';
            app.ZPu_VlowSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryCktPhaseCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryCktPhaseCurrentAEditFieldLabel.Text = 'Primary Ckt Phase Current(A)';
            app.PrimaryCktPhaseCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryCktPhaseCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryCktPhaseCurrentAEditFieldLabel.Text = 'Secondary Ckt Phase Current(A)';
            app.SecondaryCktPhaseCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageRegulationEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageRegulationEditFieldLabel.Text = 'Voltage Regulation(%)';
            app.VoltageRegulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseValueofZ_VhighSideEditFieldLabel = uilabel(app.UIFigure);
            app.BaseValueofZ_VhighSideEditFieldLabel.Text = 'Base Value of Z_VhighSide';
            app.BaseValueofZ_VhighSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseValueofZ_VlowSideEditFieldLabel = uilabel(app.UIFigure);
            app.BaseValueofZ_VlowSideEditFieldLabel.Text = 'Base Value of Z_VlowSide';
            app.BaseValueofZ_VlowSideEditField = uieditfield(app.UIFigure, 'numeric');
            app.jLabel = uilabel(app.UIFigure);
            app.jLabel.Text = '+ j';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.jLabel_2 = uilabel(app.UIFigure);
            app.jLabel_2.Text = '+ j';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Leading', 'Lagging', 'Unity', 'Option 4'};
            app.DropDown.Value = 'Leading';
            app.jLabel_3 = uilabel(app.UIFigure);
            app.jLabel_3.Text = '+ j';
            app.jLabel_4 = uilabel(app.UIFigure);
            app.jLabel_4.Text = '+ j';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xer_3Phase_D_Y

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end